classdef PPGFeat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PPGFeatUIFigure             matlab.ui.Figure
        fEditField                  matlab.ui.control.NumericEditField
        fEditFieldLabel             matlab.ui.control.Label
        SkipSsqiCheckBox            matlab.ui.control.CheckBox
        EnterSamplingfreqfilterfreqLabel  matlab.ui.control.Label
        FHEditField                 matlab.ui.control.NumericEditField
        FHEditFieldLabel            matlab.ui.control.Label
        FLEditField                 matlab.ui.control.NumericEditField
        F1Label                     matlab.ui.control.Label
        FsHzEditField               matlab.ui.control.NumericEditField
        FsHzEditFieldLabel          matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        ExitButton                  matlab.ui.control.Button
        DataTextArea                matlab.ui.control.TextArea
        DataTextAreaLabel           matlab.ui.control.Label
        PlotpressedLamp             matlab.ui.control.Lamp
        PlotpressedLampLabel        matlab.ui.control.Label
        APGFiducialPointsLabel      matlab.ui.control.Label
        VPGFiducialPointsLabel      matlab.ui.control.Label
        PPGFiducialPointsLabel      matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        GotonextwaveformLabel       matlab.ui.control.Label
        UpdatefeaturepointsusingmodifieduserdefinedvaluesLabel  matlab.ui.control.Label
        Plotallwaveformsusingmin1andmin2Label  matlab.ui.control.Label
        Yes0NoLabel                 matlab.ui.control.Label
        canddpresentEditField       matlab.ui.control.NumericEditField
        canddpresentEditFieldLabel  matlab.ui.control.Label
        StandardAPGFeaturesLabel    matlab.ui.control.Label
        StandardVPGFeaturesLabel    matlab.ui.control.Label
        StandardPPGFeaturesLabel    matlab.ui.control.Label
        eEditField                  matlab.ui.control.NumericEditField
        eEditFieldLabel             matlab.ui.control.Label
        dEditField                  matlab.ui.control.NumericEditField
        dEditFieldLabel             matlab.ui.control.Label
        cEditField                  matlab.ui.control.NumericEditField
        cmLabel                     matlab.ui.control.Label
        bEditField                  matlab.ui.control.NumericEditField
        bEditFieldLabel             matlab.ui.control.Label
        aEditField                  matlab.ui.control.NumericEditField
        aEditFieldLabel             matlab.ui.control.Label
        zEditField                  matlab.ui.control.NumericEditField
        zEditFieldLabel             matlab.ui.control.Label
        yEditField                  matlab.ui.control.NumericEditField
        yEditFieldLabel             matlab.ui.control.Label
        xEditField                  matlab.ui.control.NumericEditField
        xEditFieldLabel             matlab.ui.control.Label
        wEditField                  matlab.ui.control.NumericEditField
        wEditFieldLabel             matlab.ui.control.Label
        DEditField                  matlab.ui.control.NumericEditField
        DEditFieldLabel             matlab.ui.control.Label
        NEditField                  matlab.ui.control.NumericEditField
        NEditFieldLabel             matlab.ui.control.Label
        SEditField                  matlab.ui.control.NumericEditField
        SEditFieldLabel             matlab.ui.control.Label
        OEditField                  matlab.ui.control.NumericEditField
        OEditFieldLabel             matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        Label                       matlab.ui.control.Label
        SsqiEditField               matlab.ui.control.NumericEditField
        SsqiEditFieldLabel          matlab.ui.control.Label
        UpdateButton                matlab.ui.control.Button
        SkipforbadSsqiButton        matlab.ui.control.Button
        unfit                       matlab.ui.control.Lamp
        acceptable                  matlab.ui.control.Lamp
        good                        matlab.ui.control.Lamp
        PPGsegmentAPGsegmentFeaturesSegmentlocationsLabel  matlab.ui.control.Label
        SQI082Good03SQI081AcceptableSQI03UnfitLabel  matlab.ui.control.Label
        GenerateoutputButton        matlab.ui.control.Button
        IDEditField                 matlab.ui.control.NumericEditField
        IDLabel                     matlab.ui.control.Label
        NextButton                  matlab.ui.control.Button
        PlotButton                  matlab.ui.control.Button
        Min2EditField               matlab.ui.control.NumericEditField
        Min2EditFieldLabel          matlab.ui.control.Label
        Min1EditField               matlab.ui.control.NumericEditField
        Image4                      matlab.ui.control.Image
        Min1EditFieldLabel          matlab.ui.control.Label
        CSVwithSsqianddataIDLabel   matlab.ui.control.Label
        LoadSsqiButton              matlab.ui.control.Button
        RAWPPGdataLabel             matlab.ui.control.Label
        LoadPPGButton               matlab.ui.control.Button
        Label_3                     matlab.ui.control.Label
        Image5                      matlab.ui.control.Image
        Image6                      matlab.ui.control.Image
        Label_4                     matlab.ui.control.Label
        UIAxes6                     matlab.ui.control.UIAxes
        UIAxes5                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        PPGAnalObject
    end

    methods (Access = private)
        
        function updated_plot(app)
            ppg_anal = app.PPGAnalObject;
            %for PPG
            P_seg = plot(app.UIAxes2,ppg_anal.seg);
            %addToolbarExplorationButtons(P_seg);
            line(app.UIAxes2,[0 length(ppg_anal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment
                for ii = 1:4
                    datatip(P_seg,ppg_anal.OSND_time(ii),ppg_anal.OSND(ii),'FontSize',8);
                end

            %for VPG
            P_vpg = plot(app.UIAxes6,ppg_anal.VPG);
            %addToolbarExplorationButtons(P_vpg);
            line(app.UIAxes6,[0 length(ppg_anal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment
                for ii = 1:4
                    datatip(P_vpg,ppg_anal.WXYZ_time(ii),ppg_anal.WXYZ(ii),'FontSize',8);
                end

            %for APG
            P_apg = plot(app.UIAxes5,ppg_anal.APG);
            %addToolbarExplorationButtons(P_apg);
            line(app.UIAxes5,[0 length(ppg_anal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment
                if ((ppg_anal.c_and_d_present == 1)) %withh c and d, updated data tip
                        for ii = 1:5
                                datatip(P_apg,ppg_anal.abcde_time(ii),ppg_anal.abcde(ii),'FontSize',8);
                        end
                        datatip(P_apg,ppg_anal.f,ppg_anal.APG(ppg_anal.f),'FontSize',8);
                end
                if ((ppg_anal.c_and_d_present == 0)) %without c and d updated plot
% 
%                         datatip(P_apg,app.abcde_time(1),app.abcde(1),'FontSize',8);
%                         datatip(P_apg,app.abcde_time(2),app.abcde(2),'FontSize',8);
%                         datatip(P_apg,app.abcde_time(5),app.abcde(5),'FontSize',8);
%                         ll = [app.abcde(2) app.abcde(5)];
%                         hold(app.UIAxes5,"on")
%                         plot(app.UIAxes5,[app.abcde_time(2) app.abcde_time(5)],ll)
%                         hold(app.UIAxes5,"off")
                        for ii = 1:5
                                datatip(P_apg,ppg_anal.abcde_time(ii),ppg_anal.abcde(ii),'FontSize',8);
                        end
                         datatip(P_apg,ppg_anal.f,ppg_anal.APG(ppg_anal.f),'FontSize',8);


                end
        end    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PPGAnalObject = ppg_anal;
        end

        % Button pushed function: LoadPPGButton
        function LoadPPGButtonPushed(app, event)


            %start the counter for reading all PPG data
            app.PPGAnalObject.next = 1;

            %read initial parameters for filter
            app.PPGAnalObject.Fs = app.FsHzEditField.Value;
            app.PPGAnalObject.FL = app.FLEditField.Value;
            app.PPGAnalObject.FH = app.FHEditField.Value;


            %open file path
            %REMEMBER TO CHANGE
            [file,path]= uigetfile('D:\Research\Examensarbete\Datasets\*.csv');  %read CVS file
            %[file,path]= uigetfile('*.csv');  %read CVS file
            filename = [path file];
            app.PPGAnalObject.loadedData = importdata(filename);  %load all RAW data
            plot(app.UIAxes4,app.PPGAnalObject.loadedData(1,:))  %Plot first RAW waveform
            %plot(app.UIAxes,app.loadedData(1,:))  %Plot first waveform

            app.PPGAnalObject.Size_loaded_data = size(app.PPGAnalObject.loadedData); %read size of loaded data
            
            %variable to store location of min and max with data id
            app.PPGAnalObject.SEG_min_max = zeros(app.PPGAnalObject.Size_loaded_data(1),3);

            app.PPGAnalObject.PPG_filtered = zeros(app.PPGAnalObject.Size_loaded_data); %Create matrix to store filtered PPG
            app.PPGAnalObject.PPG_SEG = zeros(app.PPGAnalObject.Size_loaded_data(1),(app.PPGAnalObject.Size_loaded_data(2)-(app.PPGAnalObject.Size_loaded_data(2)/3))) ; %create matrix to store segment

            %VPG data base
            %app.VPG = zeros(app.Size_loaded_data(1),(app.Size_loaded_data(2)-700)) ;
            
            % APG database
            app.PPGAnalObject.APG_SEG = zeros(app.PPGAnalObject.Size_loaded_data(1),(app.PPGAnalObject.Size_loaded_data(2)-(app.PPGAnalObject.Size_loaded_data(2)/3))) ;

            %show data numbr in the text area 
            Dat1 = num2str(app.PPGAnalObject.Size_loaded_data(1));
            Dat2 = num2str(app.PPGAnalObject.next);
            Dat = Dat2 + " of " + Dat1;
            app.DataTextArea.Value = Dat;
            %app.DataTextArea.Value = num2str(app.next);


                        %test about SQI
            if (app.SkipSsqiCheckBox.Value == true)
                %disp('true')
                %A = ones(app.Size_loaded_data(1),2);
                A = ones(app.PPGAnalObject.Size_loaded_data(1),1);
                B = 1:1:app.PPGAnalObject.Size_loaded_data(1);
                B = B';
                app.PPGAnalObject.Ssqi = [A B];


                app.SsqiEditField.Value = app.PPGAnalObject.Ssqi(app.PPGAnalObject.next,1);
                app.PPGAnalObject.Sub_ID = app.PPGAnalObject.Ssqi(app.PPGAnalObject.next,2);
                app.IDEditField.Value = app.PPGAnalObject.Sub_ID;
    
                if app.SsqiEditField.Value > 0.8
                    app.Lamp.Color = 'g';
                end
    
                if (app.SsqiEditField.Value < 0.8) && (app.SsqiEditField.Value >= 0.3)
                    app.Lamp.Color = 'y';
    
                end
    
                if app.SsqiEditField.Value < 0.3
                    app.Lamp.Color = 'r';
                end
    
                app.PlotpressedLamp.Color = 'r';
            end

            %Create Filter 
            % Read all PPG RAW data and apply filters and store

            [A,B,C,F] = cheby2(4,20,[app.PPGAnalObject.FL app.PPGAnalObject.FH]/(app.PPGAnalObject.Fs/2));
            Fil = designfilt('bandpassiir','FilterOrder',4, ...
                'StopbandFrequency1',app.PPGAnalObject.FL,'StopbandFrequency2',app.PPGAnalObject.FH, ...
                'StopbandAttenuation',20,'SampleRate',app.PPGAnalObject.Fs);
            [app.PPGAnalObject.filter_sos , app.PPGAnalObject.g] = ss2sos(A,B,C,F);
            
            for i = 1:app.PPGAnalObject.Size_loaded_data(1)
                %apply filter to all data

                filtered_data = filtfilt(app.PPGAnalObject.filter_sos,app.PPGAnalObject.g,app.PPGAnalObject.loadedData(i,:));

                %normalizing the data
                m = mean(filtered_data);
                st = std(filtered_data);
                app.PPGAnalObject.PPG_filtered(i,:) = (filtered_data - m)/st; %store filtered data
                %PPG_FILTERED(i,:) = smoothdata(PPG_FILTERED(i,:),"gaussian","SmoothingFactor",0.25);

            end

            %plot the filtered PPG and show the maxima and minima
            filtered_data = app.PPGAnalObject.PPG_filtered(app.PPGAnalObject.next ,:);
            PPG = plot(app.UIAxes,filtered_data ); %Plot filtered PPG
            %addToolbarExplorationButtons(PPG);
            grid(app.UIAxes,"on")

                        %find maxima and minima of current segment
            PPG_max = islocalmax(filtered_data ,"MinProminence",0.1,"FlatSelection","all",...
                "MinSeparation",200,"MaxNumExtrema",10);
            PPG_min = islocalmin(filtered_data ,"MinProminence",0.1,"FlatSelection","all",...
                "MinSeparation",100,"MaxNumExtrema",10);

            index_PPG_max = find(PPG_max); %index of maxima points
            value_PPG_max = filtered_data(PPG_max);  %value at maxima point

            index_PPG_min = find( PPG_min) ; %index of maxima points
            value_PPG_min = filtered_data( PPG_min) ;  %value at maxima point


            if (~isempty(index_PPG_max))
                %ax = gca;
                %chart = ax.Children();
                for ii = 1:length(index_PPG_max)
                    datatip(PPG,index_PPG_max(ii),value_PPG_max(ii),'FontSize',8);
                end
            end


            if (~isempty(index_PPG_min))
%                 ax = gca;
%                 chart = ax.Children();
                for ij = 1:length(index_PPG_min)
                    datatip(PPG,index_PPG_min(ij),value_PPG_min(ij),'FontSize',8,'Location','southeast');
                end
            end

            %read min1 and min from ppg waveform and show in the edit field

                app.Min1EditField.Value = index_PPG_min(1);
                app.Min2EditField.Value = index_PPG_min(2);

            %initialize the variables
            app.PPGAnalObject.OSND = zeros(1,4);
            app.PPGAnalObject.WXYZ = zeros(1,4);
            app.PPGAnalObject.abcde = zeros(1,5);
            app.PPGAnalObject.feature = zeros(219,30);
            app.PPGAnalObject.c_d_APG = zeros(219,1);

          
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.PlotpressedLamp.Color = 'g'; %to show user that the plot button has not been pressed
            
            %plot the segment using the manual input values from min1 and
            %min2
            min1 = app.Min1EditField.Value-15;
            min2 = app.Min2EditField.Value+15;
            app.PPGAnalObject.seg = app.PPGAnalObject.PPG_filtered(app.PPGAnalObject.next,min1:min2);
            %app.seg = normalize(app.seg,"range");
            %calculate and filter VPG

            %calculate 2.5% of T
            app.PPGAnalObject.T2_5 = floor((((min2 - min1)-30)/100)*2.5);
          %disp(app.T2_5)


            app.PPGAnalObject.VPG  = diff(app.PPGAnalObject.seg)*1000;
            app.PPGAnalObject.VPG = smoothdata(app.PPGAnalObject.VPG,"movmean",50); %data smoothing using 50 ms window
            %app.VPG = filtfilt(app.filter_sos,app.g,app.VPG);

            %calculate and filter APG
            app.PPGAnalObject.APG  = diff(app.PPGAnalObject.VPG)*1000;
            app.PPGAnalObject.APG  = smoothdata(app.PPGAnalObject.APG,"movmean",65); %data smoothing using 65 ms window
            %app.APG = filtfilt(app.filter_sos,app.g,app.APG );

            %create matrix for segment information

            app.PPGAnalObject.SEG_min_max(app.PPGAnalObject.next,1) = app.PPGAnalObject.Sub_ID;
            app.PPGAnalObject.SEG_min_max(app.PPGAnalObject.next,2) = min1;
            app.PPGAnalObject.SEG_min_max(app.PPGAnalObject.next,3) = min2;
            app.PPGAnalObject.SEG_min_max;

            %plot the PPG segment
            P_seg = plot(app.UIAxes2,app.PPGAnalObject.seg);
            %addToolbarExplorationButtons(P_seg);
            grid(app.UIAxes2,"on")

            line(app.UIAxes2,[0 length(app.PPGAnalObject.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment

            %find maxima and minima of current segment
            PPG_SEG_max = islocalmax(app.PPGAnalObject.seg,"MinProminence",0.1,"FlatSelection","all",...
                "MinSeparation",10,"MaxNumExtrema",2);
            PPG_SEG_min = islocalmin(app.PPGAnalObject.seg,"MinProminence",0.01,"FlatSelection","all",...
                "MinSeparation",10,"MaxNumExtrema",3);

            index_max = find(PPG_SEG_max); %index of maxima points
            value_max = app.PPGAnalObject.seg(PPG_SEG_max);  %value at maxima point

            index_min = find( PPG_SEG_min) ; %index of maxima points
            value_min = app.PPGAnalObject.seg( PPG_SEG_min) ;  %value at maxima point

            if (~isempty(index_max))
                %ax = gca;
                %chart = ax.Children();
                for ii = 1:length(index_max)
                    datatip(P_seg,index_max(ii),value_max(ii),'FontSize',8);
                end
            end


            if (~isempty(index_min))
%                 ax = gca;
%                 chart = ax.Children();
                for ij = 1:length(index_min)
                    datatip(P_seg,index_min(ij),value_min(ij),'FontSize',8,'Location','southeast');
                end
            end


            %plot VPG
            P_vpg = plot(app.UIAxes6,app.PPGAnalObject.VPG);
            %addToolbarExplorationButtons(P_vpg);
            grid(app.UIAxes6,"on")

            line(app.UIAxes6,[0 length(app.PPGAnalObject.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment

            %find maxima and minima of current segment
            PPG_vpg_max = islocalmax(app.PPGAnalObject.VPG,"MinProminence",0.2,"FlatSelection","all",...
                "MinSeparation",50,"MaxNumExtrema",3);
            PPG_vpg_min = islocalmin(app.PPGAnalObject.VPG,"MinProminence",0.2,"FlatSelection","all",...
                "MinSeparation",50,"MaxNumExtrema",2);

            index_max_vpg = find(PPG_vpg_max); %index of maxima points
            value_max_vpg = app.PPGAnalObject.VPG(PPG_vpg_max);  %value at maxima point

            index_min_vpg = find( PPG_vpg_min) ; %index of maxima points
            value_min_vpg = app.PPGAnalObject.VPG( PPG_vpg_min) ;  %value at maxima point

            if (~isempty(index_max_vpg))

                for ii = 1:length(index_max_vpg)
                    datatip(P_vpg,index_max_vpg (ii),value_max_vpg(ii),'FontSize',8);
                end
            end


            if (~isempty(index_min_vpg))

                for ij = 1:length(index_min_vpg)

                    datatip(P_vpg,index_min_vpg(ij),value_min_vpg(ij),'FontSize',8,'Location','southeast');
                end
            end

            %plot APG
            P_apg = plot(app.UIAxes5,app.PPGAnalObject.APG);
            %addToolbarExplorationButtons(P_apg);
            grid(app.UIAxes5,"on")

            line(app.UIAxes5,[0 length(app.PPGAnalObject.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment

            %find maxima and minima of current segment
            small = length(app.PPGAnalObject.seg);
                PPG_apg_max = islocalmax(app.PPGAnalObject.APG,"MinProminence",1.5,"FlatSelection","all",...
                    "MinSeparation",50,"MaxNumExtrema",5);
                PPG_apg_min = islocalmin(app.PPGAnalObject.APG,"MinProminence",1.5,"FlatSelection","all",...
                    "MinSeparation",50,"MaxNumExtrema",5);

                index_max_apg = find(PPG_apg_max); %index of maxima points
                value_max_apg = app.PPGAnalObject.APG(PPG_apg_max);  %value at maxima point

                index_min_apg = find( PPG_apg_min) ; %index of maxima points
                value_min_apg = app.PPGAnalObject.APG( PPG_apg_min) ;  %value at maxima point


%                 if (app.c_d_not == 1) %means c and d is NOT present in APG
%                     hold(app.UIAxes5,"on")
%                     ll = [app.APG(index_min_apg(1)) app.APG(index_max_apg(2))];
%                     plot(app.UIAxes5,[index_min_apg(1) index_max_apg(2)],ll)
%                     hold(app.UIAxes5,"off")
%                     app.eEditField.Value = index_max_apg(2); 
% 
%                 end
% new code on 15th MARCH 2023 to check two points close to each other
if abs(index_min_apg(1) - index_max_apg(1)) <= 50
    disp('The first minimum and maximum are close to each other by 50 units.');
    % Shift the index_min_apg vector by 1
    index_min_apg = index_min_apg(2:end);
    value_min_apg = value_min_apg(2:end);
end

                if (~isempty(index_max_apg))

                    for ii = 1:length(index_max_apg)
                        datatip(P_apg,index_max_apg (ii),value_max_apg(ii),'FontSize',8);

                    end
                end


                if (~isempty(index_min_apg))

                    for ij = 1:length(index_min_apg)

                        datatip(P_apg,index_min_apg(ij),value_min_apg(ij),'FontSize',8,'Location','southeast');
                    end
                end

            %length(app.seg)
%cal_c_d(app)  %%to test the function


%corrected datatips and indexes of APG
                app.PPGAnalObject.APG_maxima = index_max_apg ;
                app.PPGAnalObject.APG_minima = index_min_apg ;


            %read all the feature points from PPG VPG and APG
            app.OEditField.Value = index_min(1);
            app.SEditField.Value = index_max(1);

            app.PPGAnalObject.APG_c_d_test() %check the presence of c and d

            %Update fields after with ppg_anal values
            %app.OEditField.Value = app.PPGAnalObject.O;
            %app.SEditField.Value = app.PPGAnalObject.S;
            app.NEditField.Value = app.PPGAnalObject.N;
            app.DEditField.Value = app.PPGAnalObject.D;

            %app.wEditField.Value = app.PPGAnalObject.W;
            %app.xEditField.Value = app.PPGAnalObject.X;
            %app.yEditField.Value = app.PPGAnalObject.Y;
            %app.zEditField.Value = app.PPGAnalObject.Z;

            %app.aEditField.Value = app.PPGAnalObject.a;
            %app.bEditField.Value = app.PPGAnalObject.b;
            app.cEditField.Value = app.PPGAnalObject.c;
            app.dEditField.Value = app.PPGAnalObject.d;
            app.eEditField.Value = app.PPGAnalObject.e;
            app.fEditField.Value = app.PPGAnalObject.f;

            app.canddpresentEditField.Value = app.PPGAnalObject.c_and_d_present;

            datatip(P_seg,app.NEditField.Value,app.PPGAnalObject.seg(app.NEditField.Value),'FontSize',8);
            datatip(P_seg,app.DEditField.Value,app.PPGAnalObject.seg(app.DEditField.Value),'FontSize',8);
            %disp(app.NEditField.Value)
            %app.NEditField.Value = index_max_apg(3); 
            %app.DEditField.Value = index_max_vpg(2); 

            app.PPGAnalObject.OSND = [app.PPGAnalObject.seg(app.OEditField.Value) app.PPGAnalObject.seg(app.SEditField.Value) app.PPGAnalObject.seg(app.NEditField.Value) app.PPGAnalObject.seg(app.DEditField.Value)];
            %disp(app.OSND)


            app.wEditField.Value = index_max_vpg(1); 
            app.xEditField.Value = index_max(1); 
            app.yEditField.Value = index_min_vpg(1); 
            app.zEditField.Value = index_max_vpg(2); 
            datatip(P_vpg,app.xEditField.Value,app.PPGAnalObject.VPG(app.xEditField.Value),'FontSize',8);

            app.PPGAnalObject.WXYZ = [app.PPGAnalObject.VPG(app.wEditField.Value) app.PPGAnalObject.VPG(app.xEditField.Value) app.PPGAnalObject.VPG(app.yEditField.Value) app.PPGAnalObject.VPG(app.zEditField.Value)];
            %disp(app.WXYZ)

            app.aEditField.Value = index_max_apg(1); 
            app.bEditField.Value = index_min_apg(1); 
            %app.cEditField.Value = index_max_apg(2); 
            %app.dEditField.Value = index_min_apg(2); 
            %app.eEditField.Value = index_max_apg(3); 

            if (app.PPGAnalObject.c_d_not == 1) %means c and d is NOT present in APG
            app.PPGAnalObject.abcde = [app.PPGAnalObject.APG(app.aEditField.Value) app.PPGAnalObject.APG(app.bEditField.Value) app.PPGAnalObject.APG(app.cEditField.Value) app.PPGAnalObject.APG(app.dEditField.Value) app.PPGAnalObject.APG(app.eEditField.Value)];
            %disp(app.abcde)
            end

            if (app.PPGAnalObject.c_d_not == 0) %means c and d is present in APG
            app.PPGAnalObject.abcde = [app.PPGAnalObject.APG(app.aEditField.Value) app.PPGAnalObject.APG(app.bEditField.Value) app.PPGAnalObject.APG(app.cEditField.Value) app.PPGAnalObject.APG(app.dEditField.Value) app.PPGAnalObject.APG(app.eEditField.Value)];
            %disp(app.abcde)
            end

            %time variables of all waveform
            app.PPGAnalObject.OSND_time  = [app.OEditField.Value app.SEditField.Value app.NEditField.Value app.DEditField.Value];
            app.PPGAnalObject.WXYZ_time  = [app.wEditField.Value app.xEditField.Value app.yEditField.Value app.zEditField.Value];
            app.PPGAnalObject.abcde_time = [app.aEditField.Value app.bEditField.Value app.cEditField.Value app.dEditField.Value app.eEditField.Value];

            %for f values of APG
            F_Value = app.PPGAnalObject.APG(app.fEditField.Value);
            F_t = app.fEditField.Value;
            %for On+1 values of PPG
            O_next_t = ((app.Min2EditField.Value - app.Min1EditField.Value) + 16);
            %disp(O_next)
            O_next = app.PPGAnalObject.seg(O_next_t);
            %feature table
            app.PPGAnalObject.feature(app.PPGAnalObject.next,:) = [app.PPGAnalObject.OSND O_next app.PPGAnalObject.WXYZ app.PPGAnalObject.abcde F_Value app.PPGAnalObject.OSND_time O_next_t app.PPGAnalObject.WXYZ_time app.PPGAnalObject.abcde_time F_t];
            %disp(app.feature)

            %store segments by zero padding remaining values
            app.PPGAnalObject.PPG_SEG(app.PPGAnalObject.next,:) = [app.PPGAnalObject.seg zeros(1,(app.PPGAnalObject.Size_loaded_data(2)-700)-length(app.PPGAnalObject.seg))];

            app.PPGAnalObject.APG_SEG(app.PPGAnalObject.next,:) = [app.PPGAnalObject.APG zeros(1,(app.PPGAnalObject.Size_loaded_data(2)-700)-length(app.PPGAnalObject.APG))];

            %plot overall PPG segments
            hold(app.UIAxes3,"on")
            plot(app.UIAxes3,app.PPGAnalObject.seg)
            hold(app.UIAxes3,"off")
          
            %store segment min and max for data recording purpose

        end

        % Button pushed function: GenerateoutputButton
        function GenerateoutputButtonPushed(app, event)
            %save segmented data of PPG for zero padded values

            PPG_segment = 'PPG_Segments.xlsx';
            writematrix(app.PPGAnalObject.PPG_SEG,PPG_segment)
            PPG = app.PPGAnalObject.PPG_SEG;

            %save segmented data of APG for zero padded values

            APG_segment = 'APG_Segments.xlsx';
            writematrix(app.PPGAnalObject.APG_SEG,APG_segment)
            APG_s = app.PPGAnalObject.APG_SEG;

            %save location of min and max with segment
            ID_min_max = 'ID_min1_min2.xlsx';
            writematrix(app.PPGAnalObject.SEG_min_max, ID_min_max)
            SMM = app.PPGAnalObject.SEG_min_max;


            %save filtered data of the whole input PPG 219 x 2100
            PPG_filter = 'PPG_Filtered_HighSQI.xlsx';
            writematrix(app.PPGAnalObject.PPG_filtered,PPG_filter )
            PPG_fil = app.PPGAnalObject.PPG_filtered;

            %save feature table
         
            PPG_feature = 'PPG_features.xlsx';
            writematrix(app.PPGAnalObject.feature,PPG_feature )
            P_feat = app.PPGAnalObject.feature;

            %save c and d presence table
            c_and_d = 'c_d_presence.xlsx';
            writematrix(app.PPGAnalObject.c_d_APG,c_and_d)
            C_D = app.PPGAnalObject.c_d_APG;

            save 'Results30Jan' 'PPG' 'APG_s' 'SMM' 'P_feat' PPG_fil 'C_D','-mat';
            %ExitButtonPushed(app, event)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            app.PPGAnalObject.next = app.PPGAnalObject.next+1;  %increase the counter for next value

            %alert on 2nd last data
            if (app.PPGAnalObject.next+1 == app.PPGAnalObject.Size_loaded_data(1))
            msgbox("This is second last data","Alert","warn");
            end

            %autosave onlast data
            if (app.PPGAnalObject.next > app.PPGAnalObject.Size_loaded_data(1))
            msgbox("Auto saving","Alert","warn");
            GenerateoutputButtonPushed(app, event)
            ExitButtonPushed(app, event)
            end

            app.IDEditField.Value = app.PPGAnalObject.next;
            plot(app.UIAxes4,app.PPGAnalObject.loadedData(app.PPGAnalObject.next ,:)) %plot RAW
            
           %plot the filtered PPG and show the maxima and minima
            filtered_data = app.PPGAnalObject.PPG_filtered(app.PPGAnalObject.next ,:);

            PPG = plot(app.UIAxes,filtered_data ); %Plot filtered PPG
            %addToolbarExplorationButtons(PPG);
            grid(app.UIAxes,"on")


                        %find maxima and minima of current segment
            PPG_max = islocalmax(filtered_data ,"MinProminence",0.1,"FlatSelection","all",...
                "MinSeparation",200,"MaxNumExtrema",4);
            PPG_min = islocalmin(filtered_data ,"MinProminence",0.01,"FlatSelection","all",...
                "MinSeparation",200,"MaxNumExtrema",4);

            index_PPG_max = find(PPG_max); %index of maxima points
            value_PPG_max = filtered_data(PPG_max);  %value at maxima point

            index_PPG_min = find( PPG_min) ; %index of maxima points
            value_PPG_min = filtered_data( PPG_min) ;  %value at maxima point

            if (~isempty(index_PPG_max))
                %ax = gca;
                %chart = ax.Children();
                for ii = 1:length(index_PPG_max)
                    datatip(PPG,index_PPG_max(ii),value_PPG_max(ii),'FontSize',8);
                end
            end


            if (~isempty(index_PPG_min))
%                 ax = gca;
%                 chart = ax.Children();
                for ij = 1:length(index_PPG_min)
                    datatip(PPG,index_PPG_min(ij),value_PPG_min(ij),'FontSize',8,'Location','southeast');
                end
            end

            %read min1 and min from ppg waveform and show in the edit field
            
%%check for distance between mins.. call some function

% Set the threshold value for comparing the minima indices
            threshold = 40 * app.PPGAnalObject.Fs/100;
            
            % Loop over all minima indices
            for i = 1:length(index_PPG_min)-1
                
                % Check if the next minimum index is greater than the current one by
                % the threshold value
                if index_PPG_min(i+1) > index_PPG_min(i) + threshold
                    % If yes, check if there is any maximum index between the two minima
                    maxima_between_minima = index_PPG_max(index_PPG_max > index_PPG_min(i) & index_PPG_max < index_PPG_min(i+1));
                    if ~isempty(maxima_between_minima)
                        
                        % Add any other code you need to perform here
                    app.Min1EditField.Value = index_PPG_min(i);
                    app.Min2EditField.Value = index_PPG_min(i+1);
                    break
                    end
                end
            end


            app.SsqiEditField.Value = app.PPGAnalObject.Ssqi(app.PPGAnalObject.next,1); %display SQI from the input table

            app.PPGAnalObject.Sub_ID = app.PPGAnalObject.Ssqi(app.PPGAnalObject.next,2);  %display subject ID from the input table
            app.IDEditField.Value = app.PPGAnalObject.Sub_ID;
            
            if app.SsqiEditField.Value > 0.8
                app.Lamp.Color = 'g';
            end

            if (app.SsqiEditField.Value < 0.8) && (app.SsqiEditField.Value >= 0.3)
                app.Lamp.Color = 'y';

            end

            if app.SsqiEditField.Value < 0.3
                app.Lamp.Color = 'r';
            end

           app.PlotpressedLamp.Color = 'r';  %to show user that the plot button has not been pressed

           %show data numbr in the text area 
           Dat1 = num2str(app.PPGAnalObject.Size_loaded_data(1));
            Dat2 = num2str(app.PPGAnalObject.next);
            Dat = Dat2 + " of " + Dat1;
            app.DataTextArea.Value = Dat;
        end

        % Button pushed function: LoadSsqiButton
        function LoadSsqiButtonPushed(app, event)
            %AA = 0;
            [file1,path1]= uigetfile('*.csv');
            filename1 = [path1 file1];
            app.PPGAnalObject.Ssqi= importdata(filename1);
            %AA = app.Ssqi.data(:,3);
            app.SsqiEditField.Value = app.PPGAnalObject.Ssqi(app.PPGAnalObject.next,1);
            app.PPGAnalObject.Sub_ID = app.PPGAnalObject.Ssqi(app.PPGAnalObject.next,2);
            app.IDEditField.Value = app.PPGAnalObject.Sub_ID;

            if app.SsqiEditField.Value > 0.8
                app.Lamp.Color = 'g';
            end

            if (app.SsqiEditField.Value < 0.8) && (app.SsqiEditField.Value >= 0.3)
                app.Lamp.Color = 'y';

            end

            if app.SsqiEditField.Value < 0.3
                app.Lamp.Color = 'r';
            end

            app.PlotpressedLamp.Color = 'r';
           
        end

        % Button pushed function: SkipforbadSsqiButton
        function SkipforbadSsqiButtonPushed(app, event)
            NextButtonPushed(app, event);
            %          app.next = app.next+1;  %increase the counter for next value
%             app.IDEditField.Value = app.next;
%             plot(app.UIAxes4,app.loadedData(app.next ,:)) %plot RAW
%             
%             plot(app.UIAxes,app.PPG_filtered(app.next ,:)) %Plot filtered
% 
%             app.SsqiEditField.Value = app.Ssqi(app.next,1);
% 
%             app.Sub_ID = app.Ssqi(app.next,2);
%             app.IDEditField.Value = app.Sub_ID;
%             
%             if app.SsqiEditField.Value > 0.8
%                 app.Lamp.Color = 'g';
%             end
% 
%             if (app.SsqiEditField.Value < 0.8) && (app.SsqiEditField.Value >= 0.3)
%                 app.Lamp.Color = 'y';
% 
%             end
% 
%             if app.SsqiEditField.Value < 0.3
%                 app.Lamp.Color = 'r';
%             end

        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            %%Value update for PPG
            app.PPGAnalObject.OSND(1) = app.OEditField.Value; 
            app.OEditField.Value = app.PPGAnalObject.OSND(1);

            app.PPGAnalObject.OSND(2) = app.SEditField.Value; 
            app.SEditField.Value = app.PPGAnalObject.OSND(2);

            app.PPGAnalObject.OSND(3) = app.NEditField.Value; 
            app.NEditField.Value = app.PPGAnalObject.OSND(3);

            app.PPGAnalObject.OSND(4) = app.DEditField.Value; 
            app.DEditField.Value = app.PPGAnalObject.OSND(4);

            app.PPGAnalObject.OSND = [app.PPGAnalObject.seg(app.OEditField.Value) app.PPGAnalObject.seg(app.SEditField.Value) app.PPGAnalObject.seg(app.NEditField.Value) app.PPGAnalObject.seg(app.DEditField.Value)];
            %app.OSND = [app.OEditField.Value app.SEditField.Value app.NEditField.Value app.DEditField.Value];
            %app.OSND = [app.OEditField.Value app.SEditField.Value app.NEditField.Value app.DEditField.Value];
            %disp(app.OSND)

            %Value update for VPG
            app.PPGAnalObject.WXYZ(1) = app.wEditField.Value;
            app.wEditField.Value = app.PPGAnalObject.WXYZ(1); 

            app.PPGAnalObject.WXYZ(2) = app.xEditField.Value;
            app.xEditField.Value = app.PPGAnalObject.WXYZ(2); 

            app.PPGAnalObject.WXYZ(3) = app.yEditField.Value;
            app.yEditField.Value = app.PPGAnalObject.WXYZ(3); 

            app.PPGAnalObject.WXYZ(4) = app.zEditField.Value;
            app.zEditField.Value = app.PPGAnalObject.WXYZ(4); 

            app.PPGAnalObject.WXYZ = [app.PPGAnalObject.VPG(app.wEditField.Value) app.PPGAnalObject.VPG(app.xEditField.Value) app.PPGAnalObject.VPG(app.yEditField.Value) app.PPGAnalObject.VPG(app.zEditField.Value)];
            %app.WXYZ = [app.wEditField.Value app.xEditField.Value app.yEditField.Value app.zEditField.Value];
            %app.OSND = [app.OEditField.Value app.SEditField.Value app.NEditField.Value app.DEditField.Value];
            %disp(app.WXYZ)

            %value update for APG
            app.PPGAnalObject.abcde(1) = app.aEditField.Value;
            app.aEditField.Value = app.PPGAnalObject.abcde(1); 

            app.PPGAnalObject.abcde(2) = app.bEditField.Value;
            app.bEditField.Value = app.PPGAnalObject.abcde(2); 

            app.PPGAnalObject.abcde(3) = app.cEditField.Value;
            app.cEditField.Value = app.PPGAnalObject.abcde(3); 

            app.PPGAnalObject.abcde(4) = app.dEditField.Value;
            app.dEditField.Value = app.PPGAnalObject.abcde(4); 

            app.PPGAnalObject.abcde(5) = app.eEditField.Value;
            app.eEditField.Value = app.PPGAnalObject.abcde(5); 

            app.PPGAnalObject.c_d_APG(app.PPGAnalObject.next) = app.canddpresentEditField.Value;
            if (app.PPGAnalObject.c_d_APG(app.PPGAnalObject.next) == 0) %means c and d is NOT present in APG

                app.cEditField.Value = app.cEditField.Value;
                app.dEditField.Value = app.dEditField.Value;

%                 ll = [app.APG(app.abcde(2)) app.APG(app.abcde(5))];
%                 hold(app.UIAxes5,"on")
%                 plot(app.UIAxes5,[app.abcde(2) app.abcde(5)],[app.APG(app.abcde(2)) app.APG(app.abcde(5))])
%                 hold(app.UIAxes5,"off")

            app.PPGAnalObject.abcde = [app.PPGAnalObject.APG(app.aEditField.Value) app.PPGAnalObject.APG(app.bEditField.Value) app.PPGAnalObject.APG(app.cEditField.Value) app.PPGAnalObject.APG(app.dEditField.Value) app.PPGAnalObject.APG(app.eEditField.Value)];
            %disp(app.abcde)
            end

            if (app.PPGAnalObject.c_d_APG(app.PPGAnalObject.next) == 1) %means c and d is present in APG
            app.PPGAnalObject.abcde = [app.PPGAnalObject.APG(app.aEditField.Value) app.PPGAnalObject.APG(app.bEditField.Value) app.PPGAnalObject.APG(app.cEditField.Value) app.PPGAnalObject.APG(app.dEditField.Value) app.PPGAnalObject.APG(app.eEditField.Value)];
            %disp(app.abcde)
            end
            %time variables of all waveform
            app.PPGAnalObject.OSND_time  = [app.OEditField.Value app.SEditField.Value app.NEditField.Value app.DEditField.Value];
            app.PPGAnalObject.WXYZ_time  = [app.wEditField.Value app.xEditField.Value app.yEditField.Value app.zEditField.Value];
            app.PPGAnalObject.abcde_time = [app.aEditField.Value app.bEditField.Value app.cEditField.Value app.dEditField.Value app.eEditField.Value];
            
            %for f values of APG
            F_Value = app.PPGAnalObject.APG(app.fEditField.Value);
            F_t = app.fEditField.Value;
            %for On+1 values of PPG
            O_next_t = ((app.Min2EditField.Value - app.Min1EditField.Value) + 16);
            %disp(O_next)
            O_next = app.PPGAnalObject.seg(O_next_t);
            %feature table
            app.PPGAnalObject.feature(app.PPGAnalObject.next,:) = [app.PPGAnalObject.OSND O_next app.PPGAnalObject.WXYZ app.PPGAnalObject.abcde F_Value app.PPGAnalObject.OSND_time O_next_t app.PPGAnalObject.WXYZ_time app.PPGAnalObject.abcde_time F_t];
            %disp(app.feature)
            
            %update table value for c and d
            app.PPGAnalObject.c_d_APG(app.PPGAnalObject.next) = app.canddpresentEditField.Value;

            %update the figues
            updated_plot(app)
            grid(app.UIAxes5,"on")

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.PPGFeatUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PPGFeatUIFigure = uifigure('Visible', 'off');
            app.PPGFeatUIFigure.Name = 'PPGFeat';
            app.UIAxes = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes, 'Filtered PPG')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Normalized values')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes2, 'PPG Segment')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes3, 'PPG Segments')
            xlabel(app.UIAxes3, 'Samples')
            ylabel(app.UIAxes3, 'Normalized Values')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes4, 'RAW PPG')
            xlabel(app.UIAxes4, 'Samples')
            ylabel(app.UIAxes4, 'Amplitude')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes5, 'APG')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes6, 'VPG')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.Label_4 = uilabel(app.PPGFeatUIFigure);
            app.Label_4.Text = ' ';
            app.Image6 = uiimage(app.PPGFeatUIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'APG-ref.png');
            app.Image5 = uiimage(app.PPGFeatUIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'VPG-ref.png');
            app.Label_3 = uilabel(app.PPGFeatUIFigure);
            app.Label_3.Text = ' ';
            app.LoadPPGButton = uibutton(app.PPGFeatUIFigure, 'push');
            app.LoadPPGButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPPGButtonPushed, true);
            app.LoadPPGButton.Text = 'Load PPG';
            app.RAWPPGdataLabel = uilabel(app.PPGFeatUIFigure);
            app.RAWPPGdataLabel.Text = 'RAW PPG data';
            app.LoadSsqiButton = uibutton(app.PPGFeatUIFigure, 'push');
            app.LoadSsqiButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSsqiButtonPushed, true);
            app.LoadSsqiButton.Text = 'Load Ssqi';
            app.CSVwithSsqianddataIDLabel = uilabel(app.PPGFeatUIFigure);
            app.CSVwithSsqianddataIDLabel.Text = 'CSV with Ssqi and data ID';
            app.Min1EditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.Min1EditFieldLabel.Text = 'Min1';
            app.Image4 = uiimage(app.PPGFeatUIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'PPG-ref.png');
            app.Min1EditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.Min2EditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.Min2EditFieldLabel.Text = 'Min2';
            app.Min2EditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.PlotButton = uibutton(app.PPGFeatUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.NextButton = uibutton(app.PPGFeatUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.IDLabel = uilabel(app.PPGFeatUIFigure);
            app.IDLabel.Text = 'ID ';
            app.IDEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.GenerateoutputButton = uibutton(app.PPGFeatUIFigure, 'push');
            app.GenerateoutputButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateoutputButtonPushed, true);
            app.GenerateoutputButton.Text = 'Generate output';
            app.SQI082Good03SQI081AcceptableSQI03UnfitLabel = uilabel(app.PPGFeatUIFigure);
            app.SQI082Good03SQI081AcceptableSQI03UnfitLabel.Text = {'SQI > 0.82       = Good'; '0.3<SQI<0.81  = Acceptable'; 'SQI <0.3          = Unfit'};
            app.PPGsegmentAPGsegmentFeaturesSegmentlocationsLabel = uilabel(app.PPGFeatUIFigure);
            app.PPGsegmentAPGsegmentFeaturesSegmentlocationsLabel.Text = {'PPG segment'; 'APG segment'; 'Features'; 'Segment locations'};
            app.good = uilamp(app.PPGFeatUIFigure);
            app.acceptable = uilamp(app.PPGFeatUIFigure);
            app.unfit = uilamp(app.PPGFeatUIFigure);
            app.SkipforbadSsqiButton = uibutton(app.PPGFeatUIFigure, 'push');
            app.SkipforbadSsqiButton.ButtonPushedFcn = createCallbackFcn(app, @SkipforbadSsqiButtonPushed, true);
            app.SkipforbadSsqiButton.Text = 'Skip for bad Ssqi';
            app.UpdateButton = uibutton(app.PPGFeatUIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.SsqiEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.SsqiEditFieldLabel.Text = 'Ssqi';
            app.SsqiEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.Label = uilabel(app.PPGFeatUIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.PPGFeatUIFigure);
            app.OEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.OEditFieldLabel.Text = 'O';
            app.OEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.SEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.SEditFieldLabel.Text = 'S';
            app.SEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.NEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.DEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.wEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.xEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.aEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.cmLabel = uilabel(app.PPGFeatUIFigure);
            app.cmLabel.Text = 'c ';
            app.cEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.dEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.dEditFieldLabel.Text = 'd ';
            app.dEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.eEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.StandardPPGFeaturesLabel = uilabel(app.PPGFeatUIFigure);
            app.StandardPPGFeaturesLabel.Text = 'Standard PPG Features';
            app.StandardVPGFeaturesLabel = uilabel(app.PPGFeatUIFigure);
            app.StandardVPGFeaturesLabel.Text = 'Standard VPG Features';
            app.StandardAPGFeaturesLabel = uilabel(app.PPGFeatUIFigure);
            app.StandardAPGFeaturesLabel.Text = 'Standard APG Features';
            app.canddpresentEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.canddpresentEditFieldLabel.Text = 'c and d present ';
            app.canddpresentEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.Yes0NoLabel = uilabel(app.PPGFeatUIFigure);
            app.Yes0NoLabel.Text = '1: Yes, 0: No';
            app.Plotallwaveformsusingmin1andmin2Label = uilabel(app.PPGFeatUIFigure);
            app.Plotallwaveformsusingmin1andmin2Label.Text = {'Plot all waveforms using '; 'min1 and min2'};
            app.UpdatefeaturepointsusingmodifieduserdefinedvaluesLabel = uilabel(app.PPGFeatUIFigure);
            app.UpdatefeaturepointsusingmodifieduserdefinedvaluesLabel.Text = {'Update feature points using'; 'modified user defined values'};
            app.GotonextwaveformLabel = uilabel(app.PPGFeatUIFigure);
            app.GotonextwaveformLabel.Text = 'Go to next waveform ';
            app.Label_2 = uilabel(app.PPGFeatUIFigure);
            app.Label_2.Text = 'PPG Filtering, Segmentation & Fiducial Point Extraction Toolbox';
            app.PPGFiducialPointsLabel = uilabel(app.PPGFeatUIFigure);
            app.PPGFiducialPointsLabel.Text = 'PPG Fiducial Points';
            app.VPGFiducialPointsLabel = uilabel(app.PPGFeatUIFigure);
            app.VPGFiducialPointsLabel.Text = 'VPG Fiducial Points';
            app.APGFiducialPointsLabel = uilabel(app.PPGFeatUIFigure);
            app.APGFiducialPointsLabel.Text = 'APG Fiducial Points';
            app.PlotpressedLampLabel = uilabel(app.PPGFeatUIFigure);
            app.PlotpressedLampLabel.Text = 'Plot pressed';
            app.PlotpressedLamp = uilamp(app.PPGFeatUIFigure);
            app.DataTextAreaLabel = uilabel(app.PPGFeatUIFigure);
            app.DataTextAreaLabel.Text = 'Data';
            app.DataTextArea = uitextarea(app.PPGFeatUIFigure);
            app.ExitButton = uibutton(app.PPGFeatUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Label_5 = uilabel(app.PPGFeatUIFigure);
            app.Label_5.Text = '';
            app.FsHzEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.FsHzEditFieldLabel.Text = 'Fs  (Hz)';
            app.FsHzEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.FsHzEditField.Value = 1000;
            app.F1Label = uilabel(app.PPGFeatUIFigure);
            app.F1Label.Text = 'FL';
            app.FLEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.FLEditField.Value = 0.4;
            app.FHEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.FHEditFieldLabel.Text = 'FH';
            app.FHEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.FHEditField.Value = 8;
            app.EnterSamplingfreqfilterfreqLabel = uilabel(app.PPGFeatUIFigure);
            app.EnterSamplingfreqfilterfreqLabel.Text = '  Enter Sampling freq & filter freq';
            app.SkipSsqiCheckBox = uicheckbox(app.PPGFeatUIFigure);
            app.SkipSsqiCheckBox.Text = 'Skip Ssqi';
            app.SkipSsqiCheckBox.Value = true;
            app.fEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            % Show the figure after all components are created
            app.PPGFeatUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PPGFeat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PPGFeatUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PPGFeatUIFigure)
        end
    end
end